classdef VisualizationWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualizationWindowUIFigure     matlab.ui.Figure
        Label                           matlab.ui.control.Label
        ReadyLamp_Label                 matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        Panel                           matlab.ui.container.Panel
        SelectWearableListBoxLabel      matlab.ui.control.Label
        SelectWearableListBox           matlab.ui.control.ListBox
        SpectrogramContrastLabel        matlab.ui.control.Label
        ContrastSwitch                  matlab.ui.control.Switch
        TypeSignalButtonGroup           matlab.ui.container.ButtonGroup
        ECGCleanedButton                matlab.ui.control.RadioButton
        LatestFilteredButton            matlab.ui.control.RadioButton
        RawButton                       matlab.ui.control.RadioButton
        TimestampsSpinner               matlab.ui.control.Spinner
        NumberoftimestampsLabel         matlab.ui.control.Label
        SelecttypeofEventsListBox       matlab.ui.control.ListBox
        SelecttypeofEventsListBoxLabel  matlab.ui.control.Label
        StartingDateDatePicker          matlab.ui.control.DatePicker
        StartingDateDatePickerLabel     matlab.ui.control.Label
        EndingDateDatePicker            matlab.ui.control.DatePicker
        EndingDateDatePickerLabel       matlab.ui.control.Label
        CompareAllEventsButton          matlab.ui.control.Button
        FileDropDown                    matlab.ui.control.DropDown
        SelectRecordingListBoxLabel     matlab.ui.control.Label
        SelectRecordingListBox          matlab.ui.control.ListBox
        Warning                         matlab.ui.control.Label
        Button_Next                     matlab.ui.control.Button
        Button_Previous                 matlab.ui.control.Button
        FieldSelectedLabel              matlab.ui.control.Label
        SelectFieldLabel                matlab.ui.control.Label
        SelectFieldListBox              matlab.ui.control.ListBox
        TabGroup                        matlab.ui.container.TabGroup
        Tab_1                           matlab.ui.container.Tab
        UIAxes1                         matlab.ui.control.UIAxes
        Tab_2                           matlab.ui.container.Tab
        UIAxes2_bottom                  matlab.ui.control.UIAxes
        UIAxes2_top                     matlab.ui.control.UIAxes
        Tab_3                           matlab.ui.container.Tab
        UIAxes3_topright                matlab.ui.control.UIAxes
        UIAxes3_topleft                 matlab.ui.control.UIAxes
        UIAxes3_bottom                  matlab.ui.control.UIAxes
        Tab_4                           matlab.ui.container.Tab
        UIAxes4_bottomright             matlab.ui.control.UIAxes
        UIAxes4_bottomleft              matlab.ui.control.UIAxes
        UIAxes4_topright                matlab.ui.control.UIAxes
        UIAxes4_topleft                 matlab.ui.control.UIAxes
        Tab_5                           matlab.ui.container.Tab
        UIAxes5_bottom                  matlab.ui.control.UIAxes
        UIAxes5_middle                  matlab.ui.control.UIAxes
        UIAxes5_top                     matlab.ui.control.UIAxes
        eventosTab                      matlab.ui.container.Tab
        VisualizationWindowLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        obj % obj from class NCNPERCEPT_BATCH
        tab
        status
        files_names
        items_names
        current_eventtype
        start_date
        end_date
        current_patient
        current_file
        current_indx
        current_recording
        current_wearable
        recording_limits
        filter_indx
        which_signal
        contrast
    end
    
    methods (Access = private)
        
        function getFieldItems(app)
            app.items_names = {};
            switch app.tab
                case "setup_off"
                    
                    % Get channel names from object
                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.setup_parameters;
                    for c = 1:length(temp_obj.stim_off.channel_names)
                        app.items_names{end+1} = [temp_obj.stim_off.channel_names{c} ' OFF'];
                    end
                    
                case "setup_on"
                    
                    % Get channel names from object
                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.setup_parameters;
                    for c = 1:length(temp_obj.stim_on.channel_names)
                        app.items_names{end+1} = [temp_obj.stim_on.channel_names{c} ' ON'];
                    end
                case "impedance"
                    % Get modes
                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.parameters.impedance;
                    modes = fieldnames(temp_obj.hemispheres(1));
                    modes = modes(~contains(modes, 'label'));
                    for c = 1:numel(modes)
                        app.items_names{end+1} = upper(modes{c});
                    end
                case "events_FFT"
                   
                   % Get events from object
                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.chronic_parameters;
                    for c = 1:length(temp_obj.events.date_time)
                        if ~isempty(temp_obj.events.lfp_frequency_snapshots_events{c,1}) && ...
                                strcmp( temp_obj.events.event_name{c}, app.current_eventtype ) && ...
                                temp_obj.events.date_time(c,:) >= app.start_date && ...
                                temp_obj.events.date_time(c,:) <= app.end_date + caldays(1)
                        
                        app.items_names{end+1} = datestr(temp_obj.events.date_time(c));
                        end
                    end
                    
                case "events_histogram"    
                    % Get events from object
                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.chronic_parameters;
                    for c = 1:length(temp_obj.events.date_time)
                        if strcmp( temp_obj.events.event_name{c}, app.current_eventtype ) && ...
                                temp_obj.events.date_time(c,:) >= app.start_date && ...
                                temp_obj.events.date_time(c,:) <= app.end_date + caldays(1)
                        app.items_names{end+1} = datestr(temp_obj.events.date_time(c));
                        end
                    end

                case "ETA"
                    % Get events from object
                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.chronic_parameters;
                    chronic_time = temp_obj.time_domain.time;
                    events_timepoint = temp_obj.events.date_time(strcmp(temp_obj.events.event_name, app.current_eventtype));
                    for c = 1:length(events_timepoint)
                        app.items_names{end+1} = datestr(events_timepoint(c));
                    end
%                     app.items_names = {datestr(events_timepoint)};
                case "survey"
                    % Get recordings and their corresponding items
                    num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters.time_domain.channel_names);

                    app.SelectRecordingListBox.Items = string(1:num_recordings);
                    app.current_recording = app.SelectRecordingListBox.Value;

                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters;
                    temp_current = str2double(app.current_recording);
                    for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                        app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                    end

                case "indefinite"
                    % Get recordings and their corresponding items
                    num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.indefinite_obj.indefinite_parameters.time_domain.channel_names);

                    app.SelectRecordingListBox.Items = string(1:num_recordings);
                    app.current_recording = app.SelectRecordingListBox.Value;

                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.indefinite_obj.indefinite_parameters;
                    temp_current = str2double(app.current_recording);
                    for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                        app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                    end

                case "survey_fft"

                    % Get recordings and their corresponding items
                    num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters.time_domain.channel_names);

                    app.SelectRecordingListBox.Items = string(1:num_recordings);
                    app.current_recording = app.SelectRecordingListBox.Value;

                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters;
                    temp_current = str2double(app.current_recording);
                    for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                        app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                    end

                case "survey_filt"

                    % Get recordings and their corresponding items
                    num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters.time_domain.channel_names);

                    app.SelectRecordingListBox.Items = string(1:num_recordings);
                    app.current_recording = app.SelectRecordingListBox.Value;

                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters;
                    temp_current = str2double(app.current_recording);
                    for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                        app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                    end

                case "Stream summary"
                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2num(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "Stream Spectrogram"

                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);
    
                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;
    
                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2num(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end
    
                case "Stream Welch"

                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2num(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "Stream Wavelet"

                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "Stream AutoCorr"

                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "Stream CrossCorr"

                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                case "Stream Coherence"

                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                case "Stream Coherence_band"

                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                case "One Filter"

                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "One Filter indx"

                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "Multi Filter"

                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "Wearable"
                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2num(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end


                otherwise
                    
            end
            
            app.SelectFieldListBox.Items = app.items_names;
            app.SelectFieldListBox.ItemsData = 1:numel(app.items_names);
            
        end
        
%         function setToolbar(app, ax)
%             tb = axtoolbar(ax,{'export','datacursor','zoomin','zoomout','restoreview'});
%             ax.Interactions = [panInteraction('Dimensions','x'), zoomInteraction('Dimensions','x'), dataTipInteraction];
%             %             ax.Toolbar.Visible = 'off';
%             btn = axtoolbarbtn(tb,'push');
%             btn.Icon = 'icon.png';
%             btn.Tooltip = 'Glassifier';
%             btn.ButtonPushedFcn = @(btn, ax)createGlassifyingWindow(btn, ax, app.tab);
%             
%             function createGlassifyingWindow(~, ax, tab)
%                 
%                 main_axes = ax.Axes;
%                 h = get(main_axes, 'Children');
%                 rect = drawrectangle(main_axes, 'Color', 'm');
%                 pos = rect.Position;
%                 
%                 % Create new figure with copy of main_axes:
%                 f = figure('DeleteFcn', @(src,evt)figDelete(evt,rect));
%                 fax = axes('Parent', f);
%                 fax.XRuler = main_axes.XRuler;
%                 copyobj(h, fax);
%                 %fax.Toolbar.Visible = 'off';
% %                 axis('tight');
%                 zoom_axes = axes();
%                 zoom_axes.XRuler = main_axes.XRuler;
%                 
%                 zoom_axes.XLabel.String = main_axes.XLabel.String;
%                 zoom_axes.YLabel.String = main_axes.YLabel.String;
%                 zoom_axes.YDir = main_axes.YDir;
%                 zoom_axes.Title.String = '> ZOOM WINDOW <';
%                 
% %                 if tab == "chronic"
% %                     datetick(zoom_axes, 'x','dd-mmm-yy', 'keeplimits');
% %                 end
%                 
%                 
%                 % Create Draggable Rectange in main_axes with callback to change limits of zoom_axes               
%                 addlistener(rect, 'MovingROI', @(src,evt) rectCallback(evt,zoom_axes));
%                 axes( zoom_axes );
%                 axis([pos(1),pos(1)+pos(3),pos(2),pos(2)+pos(4)]);
%                 
%                 % Callbacks for Zoom and Pan tools in zoom_axes to change size of Rectangle in main_axes
%                 h_pan = pan;
%                 h_pan.ActionPostCallback  = {@mypostcallback, rect};
%                 h_zoom = zoom;
%                 h_zoom.ActionPostCallback  = {@mypostcallback, rect};
%                 
%                 % Callbacks:
%                 function figDelete(~,hdl)
%                     delete(hdl);
%                 end
%                 
%                 function rectCallback(evt,h)
%                     p = evt.CurrentPosition;
%                     axes(h);
%                     axis([p(1),p(1)+p(3),p(2),p(2)+p(4)]);
%                     ref = num2str( p(1) + 0.5*p(3) );
%                     h.Title.String = ['> ZOOM WINDOW < : [', ref, ']' ];
%                     
%                 end
%                 
%                 function mypostcallback(evd, rect)
%                     newXlim = evd.Axes.XLim;
%                     newYlim = evd.Axes.YLim;
%                     
%                     xmin = newXlim(1);
%                     ymin = newYlim(1);
%                     width = newXlim(2) -  newXlim(1);
%                     height = newYlim(2) -  newYlim(1);
%                     
%                     pos_xy = [xmin, ymin, width, height];
%                     setPosition(rect, pos_xy );
%                 end
%             end
%         end
        
        function getEventsNames(app)
            events_names = {};
            temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj;
            for evnt = 1:length(temp_obj.chronic_parameters.type_events)
                if ~any(cellfun(@(x) isequal(x, temp_obj.chronic_parameters.type_events{evnt}), events_names)) && ...
                        ~isempty(temp_obj.chronic_parameters.events.lfp_frequency_snapshots_events( ...
                        strcmp(temp_obj.chronic_parameters.events.event_name(:),temp_obj.chronic_parameters.type_events{evnt}),1))
                    events_names{end+1} = temp_obj.chronic_parameters.type_events{evnt};
                end
            end
            app.SelecttypeofEventsListBox.Items = sort(events_names);
            app.SelecttypeofEventsListBox.Value = app.SelecttypeofEventsListBox.Items(1);
            app.current_eventtype = app.SelecttypeofEventsListBox.Value;
        end

        function getWearablesNames(app)
            wearables_names     = {};
            streaming_obj       = app.obj.ncnpercept_patient{ app.current_patient }{ app.current_file }.streaming_obj.streaming_parameters;
            streaming_first_dt  = streaming_obj.time_domain.first_packet_datetimes{ str2num(app.current_recording) };
            wearable_obj        = app.obj.ncnpercept_patient{ app.current_patient }{ app.current_file }.wearables_obj;
            
            start_dt = streaming_first_dt;
            end_dt = streaming_first_dt + seconds( streaming_obj.time_domain.time{ str2num(app.current_recording) }(end) ) ...
                - seconds( streaming_obj.time_domain.time{ str2num(app.current_recording) }(1) );

            app.recording_limits = [start_dt end_dt];

            for i = 1:numel( wearable_obj.data )
                if any( isbetween( wearable_obj.data( i ).time, start_dt, end_dt ) )
                    fields = fieldnames( wearable_obj.data( i ) );
                    fields = fields( ~ismember( fields, ["time", "wearableID", "task", "titration", "sampling_freq_Hz", "session_start"] ) );
                    for j = 1:numel(fields)
                        wearables_names{end + 1} = [char(wearable_obj.data( i ).wearableID + " " + wearable_obj.data( i ).titration + " - " + fields(j))];
                    end
                end

            end
            
            if ~isempty( wearables_names )
                app.SelectWearableListBox.Items = sort(wearables_names);
                app.SelectWearableListBox.Value = app.SelectWearableListBox.Items(1);
                app.current_wearable            = app.SelectWearableListBox.Value;
            else
                app.SelectWearableListBox.Items = {};
            end
        end
        
        
        function axes = getAxesFromTab(app)
            switch app.TabGroup.SelectedTab
                case app.Tab_1
                    axes = [app.UIAxes1];
                case app.Tab_2
                    axes = [app.UIAxes2_top, app.UIAxes2_bottom];
                case app.Tab_3
                    axes = [app.UIAxes3_topleft, app.UIAxes3_topright, ...
                        app.UIAxes3_bottom];
                case app.Tab_4
                    axes = [app.UIAxes4_topleft, app.UIAxes4_bottomleft, ...
                            app.UIAxes4_topright, app.UIAxes4_bottomright];
                case app.Tab_5
                    axes = [app.UIAxes5_top, app.UIAxes5_middle, ...
                        app.UIAxes5_bottom];

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj_arg, patient_arg, tab_arg, filter_indx_arg)
            app.obj = obj_arg; % NCNPERCEPT objects argument from Main GUI
            app.tab = tab_arg;
            app.filter_indx = filter_indx_arg;
            app.current_patient = patient_arg;
            app.current_file = 1;
            app.current_indx = 1;
            app.status = zeros(length(app.obj.ncnpercept_patient{app.current_patient}),1);
            app.Button_Previous.Enable = false;

            datacursormode(app.VisualizationWindowUIFigure, 'on');
            
            app.files_names = {};
            for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                dt = app.obj.ncnpercept_patient{app.current_patient}{file}.parameters.session_date;
                app.files_names{end+1} = [num2str(year(dt)) '-' ...
                    datestr(month(dt), 'mmm') '-' num2str(day(dt)) ' Session'];
            end
            
            switch app.tab
                case "setup_off"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Setup OFF" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.setup_off;
                    end
                    app.current_file = find(app.status==1, 1);

                    app.FileDropDown.Items = app.files_names;
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    if ~isempty(app.current_file)
                        app.getFieldItems;
                        app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.plotRawDataSetupOFF( axes, app.current_indx );
%                         for i = 1:numel(axes)
%                             app.setToolbar(axes(i));
%                         end
                    end
                    
                case "setup_on"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Setup ON" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.setup_on;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = app.files_names;
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)
                        app.getFieldItems;
                        app.FieldSelectedLabel.Text = app.items_names{app.current_indx};

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.plotRawDataSetupON( axes, app.current_indx );
%                         for i = 1:numel(axes)
%                             app.setToolbar(axes(i));
%                         end
                    end
                case "impedance"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Impedance Tests" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.impedance;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = app.files_names;
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)
                        app.getFieldItems;
                        app.FieldSelectedLabel.Text = app.items_names{app.current_indx};

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.plotImpedance( axes, app.items_names{app.current_indx} );
%                         for i = 1:numel(axes)
%                             app.setToolbar(axes(i));
%                         end
                    end

                case "chronic"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Timeline" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.SelectFieldLabel.Visible = false;
                    app.SelectFieldListBox.Visible = false;
                    
                    shift = 125;
                    pos = app.TabGroup.Position;
                    pos(3) = pos(3) + shift;
                    app.TabGroup.Position = pos;
                    pos = app.FileDropDown.Position;
                    pos(3) = pos(3) + shift;
                    app.FileDropDown.Position = pos;
                    pos = app.UIAxes2_bottom.Position;
                    pos(3) = pos(3) + shift;
                    app.UIAxes2_bottom.Position = pos;
                    pos = app.UIAxes2_top.Position;
                    pos(3) = pos(3) + shift;
                    app.UIAxes2_top.Position = pos;


                    app.FieldSelectedLabel.Visible = false;
                    app.Button_Next.Visible = false;
                    app.Button_Previous.Visible = false;
                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.chronic;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = app.files_names;
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotLFPTrendLogs( axes );
%                         for i = 1:numel(axes)
%                             app.setToolbar(axes(i));
%                         end
                        
                    end
                    
                case "chronic_auto"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Chronic Auto" ;
                    app.TabGroup.SelectedTab = app.Tab_4;
                    axes = app.getAxesFromTab();
                    app.SelectFieldLabel.Visible = false;
                    app.SelectFieldListBox.Visible = false;

                    shift = 125;
                    pos = app.TabGroup.Position;
                    pos(3) = pos(3) + shift;
                    app.TabGroup.Position = pos;
                    pos = app.FileDropDown.Position;
                    pos(3) = pos(3) + shift;
                    app.FileDropDown.Position = pos;
                    pos = app.UIAxes4_bottomleft.Position;
                    pos(3) = pos(3) + round(shift/2);
                    app.UIAxes4_bottomleft.Position = pos;
                     pos = app.UIAxes4_topleft.Position;
                    pos(3) = pos(3) + round(shift/2);
                    app.UIAxes4_topleft.Position = pos;
                    pos = app.UIAxes4_topright.Position;
                    pos(1) = pos(1) + round(shift/2);
                    pos(3) = pos(3) + round(shift/2);
                    app.UIAxes4_topright.Position = pos;
                    pos = app.UIAxes4_bottomright.Position;
                    pos(1) = pos(1) + round(shift/2);
                    pos(3) = pos(3) + round(shift/2);
                    app.UIAxes4_bottomright.Position = pos;
                    
                    app.FieldSelectedLabel.Visible = false;
                    app.Button_Next.Visible = false;
                    app.Button_Previous.Visible = false;
                    
                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.events;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = app.files_names;
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)
                        
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotAutoCorrVarChronic( ...
                            axes );
                        
%                         for i = 1:numel(axes)
%                             app.setToolbar(axes(i));
%                         end
                        
                    end
                    
                case "chronic_cross"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Chronic Cross" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.SelectFieldLabel.Visible = false;
                    app.SelectFieldListBox.Visible = false;

                    shift = 125;
                    pos = app.TabGroup.Position;
                    pos(3) = pos(3) + shift;
                    app.TabGroup.Position = pos;
                    pos = app.FileDropDown.Position;
                    pos(3) = pos(3) + shift;
                    app.FileDropDown.Position = pos;
                    pos = app.UIAxes2_bottom.Position;
                    pos(3) = pos(3) + shift;
                    app.UIAxes2_bottom.Position = pos;
                    pos = app.UIAxes2_top.Position;
                    pos(3) = pos(3) + shift;
                    app.UIAxes2_top.Position = pos;

                    app.UIAxes1.Position = pos;
                    app.FieldSelectedLabel.Visible = false;
                    app.Button_Next.Visible = false;
                    app.Button_Previous.Visible = false;
                    
                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.events;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = app.files_names;
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)
                        
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotCrossCorrVarChronic( ...
                            axes );
                        
%                         for i = 1:numel(axes)
%                             app.setToolbar(axes(i));
%                         end
                        
                    end

                case "circadian"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Circadian Rhythm" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.SelectFieldLabel.Visible = false;
                    app.SelectFieldListBox.Visible = false;
                    
                    shift = 125;
                    pos = app.TabGroup.Position;
                    pos(3) = pos(3) + shift;
                    app.TabGroup.Position = pos;
                    pos = app.FileDropDown.Position;
                    pos(3) = pos(3) + shift;
                    app.FileDropDown.Position = pos;
                    pos = app.UIAxes2_bottom.Position;
                    pos(3) = pos(3) + shift;
                    app.UIAxes2_bottom.Position = pos;
                    pos = app.UIAxes2_top.Position;
                    pos(3) = pos(3) + shift;
                    app.UIAxes2_top.Position = pos;
                    
                    app.FieldSelectedLabel.Visible = false;
                    app.Button_Next.Visible = false;
                    app.Button_Previous.Visible = false;

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.chronic;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = app.files_names;
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);

                    if ~isempty(app.current_file)

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotCircadian( axes );

                    end
                    
                case "events_FFT"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Events FFT" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelecttypeofEventsListBox.Visible = true;
                    app.SelecttypeofEventsListBoxLabel.Visible = true;
                    app.StartingDateDatePicker.Visible = true;
                    app.StartingDateDatePickerLabel.Visible = true;
                    app.EndingDateDatePicker.Visible = true;
                    app.EndingDateDatePickerLabel.Visible = true;
                    app.CompareAllEventsButton.Visible = true;
                    
                    app.start_date = datetime(1900, 1, 1);
                    app.end_date = datetime('now', 'Format','d-MMM-y');
                    
                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.events_FFT;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = app.files_names;
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)
                        
                        app.getEventsNames;
                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end
                        
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotMeanFFTProfile( ...
                            [app.UIAxes2_top, app.UIAxes2_bottom], app.current_eventtype, [app.start_date app.end_date]);
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotSelectedFFTProfile( ...
                            [app.UIAxes2_top, app.UIAxes2_bottom], app.items_names{app.current_indx} );
                        
                    end
                    
                case "events_histogram"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Events Information" ;
                    app.TabGroup.SelectedTab = app.Tab_4;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelecttypeofEventsListBox.Visible = true;
                    app.SelecttypeofEventsListBoxLabel.Visible = true;
                    
                    app.start_date = datetime(1900, 1, 1);
                    app.end_date = datetime('now', 'Format','d-MMM-y');
                    
                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.events;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = app.files_names;
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)
                        
                        app.getEventsNames;
                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end
                        
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotEventHistograms( ...
                            app.items_names{app.current_indx}, app.current_eventtype,  axes );
%                         for i = 1:numel(axes)
%                             axes(i).Toolbar.Visible = 'off';
%                         end
                        
                    end

                case "ETA"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Event-Triggered Average" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
%                     app.SelectFieldLabel.Visible = false;
%                     app.SelectFieldListBox.Visible = false;
%                     app.FieldSelectedLabel.Visible = false;
%                     app.Button_Next.Visible = false;
%                     app.Button_Previous.Visible = false;
                    app.SelecttypeofEventsListBox.Visible = true;
                    app.SelecttypeofEventsListBoxLabel.Visible = true;
                    app.TimestampsSpinner.Visible = true;
                    app.NumberoftimestampsLabel.Visible = true;

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.events && ...
                            app.obj.ncnpercept_patient{app.current_patient}{file}.status.chronic;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = app.files_names;
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)
                        
                        app.getEventsNames;   
                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotETA( ...
                            [app.UIAxes2_top, app.UIAxes2_bottom], app.TimestampsSpinner.Value, app.current_eventtype, app.items_names{app.current_indx});
%                         for i = 1:numel(axes)
%                             axes(i).Toolbar.Visible = 'off';
%                         end
                        
                    end
                    
                case "survey" 
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Survey" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            survey_obj.survey_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                    end
                    app.ECGCleanedButton.Visible = false;
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.survey;
                    end

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names;
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotRawDataLFPMontage( axes, app.which_signal, str2double(app.current_recording), app.current_indx );
%                         for i = 1:numel(axes)
%                             axes(i).Toolbar.Visible = 'off';
%                         end

                     end

                case "indefinite"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Indefinite" ;
                    app.TabGroup.SelectedTab = app.Tab_1;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.indefinite;
                    end

                    if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names;
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.which_signal = 'Raw';

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.indefinite_obj.plotRawDataSurveyInd( app.UIAxes1, str2double(app.current_recording), app.current_indx );
%                         app.setToolbar(app.UIAxes1);

                    end

                case "survey_fft"

                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Survey" ;
                    app.TabGroup.SelectedTab = app.Tab_1;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.survey;
                    end

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names;
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotFFTSurvey( app.UIAxes1, str2double(app.current_recording), app.current_indx );
%                         app.setToolbar(app.UIAxes1);

                     end

                case "survey_filt"

                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Survey" ;
                    app.TabGroup.SelectedTab = app.Tab_4;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.survey;
                    end

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names;
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotFiltDataSurvey( axes, str2double(app.current_recording), app.current_indx );

%                         for i = 1:numel(axes)
%                             axes(i).Toolbar.Visible = 'off';
%                         end

                     end

                case "Stream summary" 
                    app.contrast = "normal";
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_5;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;
                    app.SpectrogramContrastLabel.Visible = true;
                    app.ContrastSwitch.Visible = true;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,39,98,22];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names(app.status==1);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSignalAndStimulation( axes(1), app.which_signal, str2double(app.current_recording), app.current_indx );
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSpectrogram( axes(2), app.which_signal, str2double(app.current_recording), app.current_indx, app.contrast );
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotScalogram_CWT( axes(3), app.which_signal, str2double(app.current_recording), app.current_indx );
                        
                        set(axes(3), 'PositionConstraint', 'innerposition');
                        set(axes(2), 'PositionConstraint', 'innerposition');
                        set(axes(1), 'PositionConstraint', 'innerposition');
                        pos3 = get(axes(3), 'InnerPosition');
                        pos2 = get(axes(2), 'InnerPosition');
                        pos1 = get(axes(1), 'InnerPosition');

                        if pos1(3) ~= pos2(3)
                            pos1(1) = pos2(1);
                            pos1(3) = pos2(3);
                            pos3(1) = pos2(1);
                            pos3(3) = pos2(3);
                            set(axes(1),'InnerPosition', pos1);
                            set(axes(3),'InnerPosition', pos3);
                        end
                        linkaxes(axes, 'x');
%                         for i = 1:numel(axes)
%                             axes(i).Toolbar.Visible = 'off';
%                         end

                     end

                case "Stream Spectrogram"
                    app.contrast = "normal";
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;
                    app.SpectrogramContrastLabel.Visible = true;
                    app.ContrastSwitch.Visible = true;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,39,98,22];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names(app.status==1);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSignalAndStimulation( axes(1), app.which_signal, str2double(app.current_recording), app.current_indx );
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSpectrogram( axes(2), app.which_signal, str2double(app.current_recording), app.current_indx, app.contrast );
                        
                        set(axes(2), 'PositionConstraint', 'innerposition');
                        set(axes(1), 'PositionConstraint', 'innerposition');
                        pos2 = get(axes(2), 'InnerPosition');
                        pos1 = get(axes(1), 'InnerPosition');

                        if pos1(3) ~= pos2(3)
                            pos1(1) = pos2(1);
                            pos1(3) = pos2(3);
                            set(axes(1),'InnerPosition', pos1);
                        end
                        linkaxes(axes, 'x');

                        %                         for i = 1:numel(axes)
                        %                             axes(i).Toolbar.Visible = 'off';
                        %                         end                        

                     end

                case "Stream Welch"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_1;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,39,98,22];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names(app.status==1);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotPWelch( axes, app.which_signal, str2double(app.current_recording), app.current_indx );
%                         for i = 1:numel(axes)
%                             axes(i).Toolbar.Visible = 'off';
%                         end

                     end

                case "Stream Wavelet"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,39,98,22];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names(app.status==1);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);
                        
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSignalAndStimulation( axes(1), app.which_signal, str2double(app.current_recording), app.current_indx );
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotScalogram_CWT( axes(2), app.which_signal, str2double(app.current_recording), app.current_indx );
                        
                        set(axes(2), 'PositionConstraint', 'innerposition');
                        set(axes(1), 'PositionConstraint', 'innerposition');
                        pos2 = get(axes(2), 'InnerPosition');
                        pos1 = get(axes(1), 'InnerPosition');

                        if pos1(3) ~= pos2(3)
                            pos1(1) = pos2(1);
                            pos1(3) = pos2(3);
                            set(axes(1),'InnerPosition', pos1);
                        end
                        linkaxes(axes, 'x');
%                         for i = 1:numel(axes)
%                             axes(i).Toolbar.Visible = 'off';
%                         end

                     end

                case "Stream AutoCorr"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,39,98,22];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names(app.status==1);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotAutoCorrVar( axes, app.which_signal, str2double(app.current_recording), app.current_indx );
%                         for i = 1:numel(axes)
%                             axes(i).Toolbar.Visible = 'off';
%                         end
                     end

                case "Stream CrossCorr"
      
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;
                    app.SelectFieldListBox.Visible = false;
                    app.SelectFieldLabel.Visible = false;
                    app.FieldSelectedLabel.Visible = false;
                    app.Button_Next.Visible = false;
                    app.Button_Previous.Visible = false;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,39,98,22];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                         app.getFieldItems;

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names(app.status==1);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCrossCorrVar( axes, app.which_signal, str2double(app.current_recording) );
%                         for i = 1:numel(axes)
%                             axes(i).Toolbar.Visible = 'off';
%                         end
                     end

                case "Stream Coherence" 
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_1;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;
                    app.SelectFieldListBox.Visible = false;
                    app.SelectFieldLabel.Visible = false;
                    app.FieldSelectedLabel.Visible = false;
                    app.Button_Next.Visible = false;
                    app.Button_Previous.Visible = false;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,39,98,22];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end

                    app.current_file = find(app.status==1, 1);

                    if ~isempty(app.current_file)

                        app.getFieldItems;

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names(app.status==1);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCoherence( axes, app.which_signal, str2double(app.current_recording) );
                        %                         for i = 1:numel(axes)
                        %                             axes(i).Toolbar.Visible = 'off';
                        %                         end
                    end
                case "Stream Coherence_band"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_1;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;
                    app.SelectFieldListBox.Visible = false;
                    app.SelectFieldLabel.Visible = false;
                    app.FieldSelectedLabel.Visible = false;
                    app.Button_Next.Visible = false;
                    app.Button_Previous.Visible = false;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,39,98,22];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end

                    app.current_file = find(app.status==1, 1);

                    if ~isempty(app.current_file)

                        app.getFieldItems;

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names(app.status==1);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCoherence_band( axes, app.which_signal, str2double(app.current_recording) );
                        %                         for i = 1:numel(axes)
                        %                             axes(i).Toolbar.Visible = 'off';
                        %                         end
                    end

                case "One Filter"
                    app.contrast = "normal";
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_4;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;
                    app.SpectrogramContrastLabel.Visible = true;
                    app.ContrastSwitch.Visible = true;

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names(app.status==1);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotFiltDataStream( -1, axes, str2double(app.current_recording), app.current_indx, app.contrast );
%                         for i = 1:numel(axes)
%                             axes(i).Toolbar.Visible = 'off';
%                         end
                     end

                case "One Filter indx"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_4;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names(app.status==1);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotFiltDataStream( app.filter_indx, axes, str2double(app.current_recording), app.current_indx );
%                         for i = 1:numel(axes)
%                             axes(i).Toolbar.Visible = 'off';
%                         end
                     end

                case "Multi Filter" 
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_5;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,316,120,228];
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = app.files_names(app.status==1);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotFiltDataStream_multi( app.filter_indx, axes, str2double(app.current_recording), app.current_indx );
%                         for i = 1:numel(axes)
%                             axes(i).Toolbar.Visible = 'off';
%                         end
                     end 


                case "Wearable"
                    app.contrast = "normal";
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Wearable" ;
                    app.TabGroup.SelectedTab = app.Tab_5;
                    axes = app.getAxesFromTab();
                    app.SelectFieldListBox.Position = [698,436,120,108];
                    pos = app.SelectRecordingListBox.Position;
                    pos(2) = pos(2) + 125;
                    app.SelectRecordingListBox.Position = pos;
                    pos = app.SelectRecordingListBoxLabel.Position;
                    pos(2) = pos(2) + 125;
                    app.SelectRecordingListBoxLabel.Position = pos;
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;
                    app.SelectWearableListBox.Visible = true;
                    app.SelectWearableListBoxLabel.Visible = true;
                    app.SpectrogramContrastLabel.Visible = true;
                    app.ContrastSwitch.Visible = true;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,39,98,22];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                    if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end
                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.getWearablesNames;

                        app.FileDropDown.Items = app.files_names(app.status==1);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSignalAndStimulation( axes(1), app.which_signal, str2double(app.current_recording), app.current_indx );
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSpectrogram( axes(2), app.which_signal, str2double(app.current_recording), app.current_indx, app.contrast );
                        if ~isempty( app.SelectWearableListBox.Items )
                            axes(3).Visible = true;
                            app.current_wearable = strsplit(app.SelectWearableListBox.Value, ' - ');
                            app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.wearables_obj.plotWearable( axes(3), app.current_wearable{1}, app.current_wearable{2}, app.recording_limits );
                        else
                            cla( axes(3), "reset" );
                            axes(3).Visible = false;
                        end

                        set(axes(3), 'PositionConstraint', 'innerposition');
                        set(axes(2), 'PositionConstraint', 'innerposition');    
                        set(axes(1), 'PositionConstraint', 'innerposition');
                        pos3 = get(axes(3), 'InnerPosition');
                        pos2 = get(axes(2), 'InnerPosition');
                        pos1 = get(axes(1), 'InnerPosition');

                        if pos1(3) ~= pos2(3)
                            pos1(1) = pos2(1);
                            pos1(3) = pos2(3);
                            pos3(1) = pos2(1);
                            pos3(3) = pos2(3);
                            set(axes(1),'InnerPosition', pos1);
                            set(axes(3),'InnerPosition', pos3);
                        end
                        linkaxes(axes, 'x');
                        %                         for i = 1:numel(axes)
                        %                             axes(i).Toolbar.Visible = 'off';
                        %                         end

                    end
                    
                otherwise
                    
            end
            
        end

        % Value changed function: SelectFieldListBox
        function SelectFieldListBoxValueChanged(app, event)
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            if app.SelectFieldListBox.Visible
                app.Button_Previous.Enable = true;
                app.Button_Next.Enable = true;
                app.current_indx = app.SelectFieldListBox.Value;
                if app.current_indx == length(app.items_names)
                    app.Button_Next.Enable = false;
                end
                if app.current_indx == 1
                    app.Button_Previous.Enable = false;
                end
                app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
            end
            drawnow;
            axes = app.getAxesFromTab();
            switch app.tab
                case "setup_off"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.plotRawDataSetupOFF( [app.UIAxes2_top, app.UIAxes2_bottom], app.current_indx );
                case "setup_on"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.plotRawDataSetupON( [app.UIAxes2_top, app.UIAxes2_bottom], app.current_indx );
                case "impedance"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.plotImpedance( [app.UIAxes2_top, app.UIAxes2_bottom], app.items_names{app.current_indx} );
                case "indefinite"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.indefinite_obj.plotRawDataSurveyInd( app.UIAxes1, str2double(app.current_recording), app.current_indx );
                case "events_FFT"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotSelectedFFTProfile( ...
                        [app.UIAxes2_top, app.UIAxes2_bottom], app.items_names{app.current_indx} );
                case "events_histogram"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotEventHistograms( ...
                            app.items_names{app.current_indx}, app.current_eventtype,  axes );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                case "chronic"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotLFPTrendLogs( axes );
%                     for i = 1:numel(axes)
%                         app.setToolbar(axes(i));
%                     end
                    return;
                case "chronic_auto"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotAutoCorrVarChronic( ...
                        axes );
%                     for i = 1:numel(axes)
%                         app.setToolbar(axes(i));
%                     end
                case "chronic_cross"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotCrossCorrVarChronic( ...
                        axes );
%                     for i = 1:numel(axes)
%                         app.setToolbar(axes(i));
%                     end
                case "circadian"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotCircadian( axes );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                case "ETA"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotETA( ...
                            [app.UIAxes2_top, app.UIAxes2_bottom], app.TimestampsSpinner.Value, app.current_eventtype, app.items_names{app.current_indx});
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                case "survey"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotRawDataLFPMontage( axes, app.which_signal, str2double(app.current_recording), app.current_indx );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                case "survey_fft"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotFFTSurvey( app.UIAxes1, str2double(app.current_recording), app.current_indx );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                case "survey_filt"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotFiltDataSurvey( axes, str2double(app.current_recording), app.current_indx );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                case "Stream summary"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSignalAndStimulation( axes(1), app.which_signal, str2double(app.current_recording), app.current_indx );
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSpectrogram( axes(2), app.which_signal, str2double(app.current_recording), app.current_indx, app.contrast );
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotScalogram_CWT( axes(3), app.which_signal, str2double(app.current_recording), app.current_indx );
                    %                     for i = 1:numel(axes)
                    %                         axes(i).Toolbar.Visible = 'off';
                    %                     end
                    linkaxes(axes, 'x');
                case "Stream Spectrogram"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSignalAndStimulation( axes(1), app.which_signal, str2double(app.current_recording), app.current_indx );
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSpectrogram( axes(2), app.which_signal, str2double(app.current_recording), app.current_indx, app.contrast );
                    %                     for i = 1:numel(axes)
                    %                         axes(i).Toolbar.Visible = 'off';
                    %                     end
                    linkaxes(axes, 'x');
                case "Stream Welch"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotPWelch( axes, app.which_signal, str2double(app.current_recording), app.current_indx );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                case "Stream Wavelet"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSignalAndStimulation( axes(1), app.which_signal, str2double(app.current_recording), app.current_indx );
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotScalogram_CWT( axes(2), app.which_signal, str2double(app.current_recording), app.current_indx );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                    linkaxes(axes, 'x');
                case "Stream AutoCorr"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotAutoCorrVar( axes, app.which_signal, str2double(app.current_recording), app.current_indx );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                case "Stream CrossCorr"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCrossCorrVar( axes, app.which_signal, str2double(app.current_recording) );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                case "Stream Coherence"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCoherence( axes, app.which_signal, str2double(app.current_recording) );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end

                case "Stream Coherence_band"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCoherence_band( axes, app.which_signal, str2double(app.current_recording) );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                case "One Filter"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotFiltDataStream( -1, axes, str2double(app.current_recording), app.current_indx, app.contrast );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                case "One Filter indx"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotFiltDataStream( app.filter_indx, axes, str2double(app.current_recording), app.current_indx );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                case "Multi Filter"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotFiltDataStream_multi( app.filter_indx, axes, str2double(app.current_recording), app.current_indx );
%                     for i = 1:numel(axes)
%                         axes(i).Toolbar.Visible = 'off';
%                     end
                case "Wearable"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSignalAndStimulation( axes(1), app.which_signal, str2double(app.current_recording), app.current_indx );
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSpectrogram( axes(2), app.which_signal, str2double(app.current_recording), app.current_indx, app.contrast );
                    app.getWearablesNames();
                    if ~isempty( app.SelectWearableListBox.Items )
                        axes(3).Visible = true;
                        app.current_wearable = strsplit(app.SelectWearableListBox.Value, ' - ');
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.wearables_obj.plotWearable( axes(3), app.current_wearable{1}, app.current_wearable{2}, app.recording_limits );
                    else
%                         cla( axes(3), "reset" );
                        axes(3).Visible = false;
                    end
                    linkaxes(axes, 'x');
                otherwise

            end

            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;

        end

        % Button pushed function: Button_Next
        function Button_NextPushed(app, event)
            app.SelectFieldListBox.Value = app.current_indx + 1;
            app.SelectFieldListBoxValueChanged;
        end

        % Button pushed function: Button_Previous
        function Button_PreviousPushed(app, event)
            app.SelectFieldListBox.Value = app.current_indx - 1;
            app.SelectFieldListBoxValueChanged;
        end

        % Value changed function: FileDropDown
        function FileDropDownValueChanged(app, event)
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;
            
            app.current_file = app.FileDropDown.Value;
            app.current_indx = 1;
            
            if app.status(app.current_file) == 0
                app.Warning.Visible = true;
                app.TabGroup.Visible = false;
                app.SelectFieldListBox.Items = {};
                app.SelectFieldListBox.Enable = false;
                app.FieldSelectedLabel.Text = "";
                app.Button_Next.Enable = false;
                app.Button_Previous.Enable = false;
                
                app.SelecttypeofEventsListBox.Items = {};
                app.SelecttypeofEventsListBox.Enable = false;
                app.StartingDateDatePicker.Enable = false;
                app.EndingDateDatePicker.Enable = false;
            else
                app.Warning.Visible = false;
                app.TabGroup.Visible = true;
                app.SelectFieldListBox.Enable = true;
                app.SelecttypeofEventsListBox.Enable = true;
                app.StartingDateDatePicker.Enable = true;
                app.EndingDateDatePicker.Enable = true;
                app.StartingDateDatePicker.Value = NaT;
                app.EndingDateDatePicker.Value = NaT;
                
                if any(contains(["chronic", "events_FFT", "events_histogram", "ETA"], app.tab))
                    app.getEventsNames;
                end
                
                if app.SelectFieldListBox.Visible
                    app.getFieldItems;
                    app.SelectFieldListBox.Value = 1;
                end
                app.SelectFieldListBoxValueChanged;

            end

            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
        end

        % Value changed function: SelecttypeofEventsListBox
        function SelecttypeofEventsListBoxValueChanged(app, event)
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;
            app.current_eventtype = app.SelecttypeofEventsListBox.Value;
            app.start_date = app.StartingDateDatePicker.Value;
            app.end_date = app.EndingDateDatePicker.Value;
            app.current_indx = 1;
            
            if isnat(app.start_date)
                app.start_date = datetime(1900, 1, 1);
            end
            
            if isnat(app.end_date)
                app.end_date = datetime('now', 'Format','d-MMM-y');
            end
            
            switch app.tab
                case "events_FFT"
                    app.getFieldItems;
                    app.SelectFieldListBox.Value = app.current_indx;

                    if ~isempty(app.items_names)
                        app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotMeanFFTProfile( ...
                            [app.UIAxes2_top, app.UIAxes2_bottom], app.current_eventtype, [app.start_date app.end_date]);
                        app.SelectFieldListBoxValueChanged;
                    else
                        cla
                    end

                case "ETA"
                    app.getFieldItems;
                    app.SelectFieldListBox.Value = app.current_indx;

                    if ~isempty(app.items_names)
                        app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        app.SelectFieldListBoxValueChanged;
                    else
                        cla
                    end
                case "events_histogram"
                    app.getFieldItems;
                    app.SelectFieldListBox.Value = app.current_indx;

                    if ~isempty(app.items_names)
                        app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        app.SelectFieldListBoxValueChanged;
                    else
                        cla
                    end
                    
            end

%             app.ReadyLamp_Label.Text = 'Ready';
%             app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;

        end

        % Value changed function: StartingDateDatePicker
        function StartingDateDatePickerValueChanged(app, event)
            app.start_date = app.StartingDateDatePicker.Value;
            app.getFieldItems;
            
            if ~isempty(app.items_names)
                app.FieldSelectedLabel.Text = app.items_names{app.current_indx};

                switch app.tab
                    case "events_FFT"
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotMeanFFTProfile( ...
                            [app.UIAxes2_top, app.UIAxes2_bottom], app.current_eventtype, [app.start_date app.end_date]);
                end
            end
            app.SelectFieldListBoxValueChanged;
        end

        % Value changed function: EndingDateDatePicker
        function EndingDateDatePickerValueChanged(app, event)
            app.end_date = app.EndingDateDatePicker.Value;
            app.getFieldItems;
            app.SelectFieldListBox.Value = app.current_indx;
            
            if ~isempty(app.items_names)
                app.FieldSelectedLabel.Text = app.items_names{app.current_indx};

                switch app.tab
                    case "events_FFT"
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotMeanFFTProfile( ...
                            [app.UIAxes2_top, app.UIAxes2_bottom], app.current_eventtype, [app.start_date app.end_date]);
                end
            end
            app.SelectFieldListBoxValueChanged;
        end

        % Value changed function: SelectRecordingListBox
        function SelectRecordingListBoxValueChanged(app, event)

            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;

            app.current_recording = app.SelectRecordingListBox.Value;

            axes = app.getAxesFromTab();

            if app.tab == "Stream CrossCorr" 
                app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCrossCorrVar( axes, app.which_signal, str2double(app.current_recording) );
%                 for i = 1:numel(axes)
%                     axes(i).Toolbar.Visible = 'off';
%                 end

            elseif app.tab == "Stream Coherence"
                app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCoherence( axes, app.which_signal, str2double(app.current_recording) );
%                 for i = 1:numel(axes)
%                     axes(i).Toolbar.Visible = 'off';
%                 end

            elseif app.tab == "Stream Coherence_band"
                app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCoherence_band( axes, app.which_signal, str2double(app.current_recording) );
%                 for i = 1:numel(axes)
%                     axes(i).Toolbar.Visible = 'off';
%                 end

            else

                if ~contains(app.tab, "Stream")
                    app.current_indx = 1;
                end

                if app.tab == "Wearable"
                    app.getWearablesNames();
                end

                app.getFieldItems;
                app.SelectFieldListBox.Value = app.current_indx;

                if ~isempty(app.items_names)
                    app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                    app.SelectFieldListBoxValueChanged;
                else
                    cla
                end

            end

            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
        end

        % Button pushed function: CompareAllEventsButton
        function CompareAllEventsButtonPushed(app, event)
            app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotMeanFFTProfile();
        end

        % Value changed function: TimestampsSpinner
        function TimestampsSpinnerValueChanged(app, event)
            app.SelectFieldListBoxValueChanged;            
        end

        % Selection changed function: TypeSignalButtonGroup
        function TypeSignalButtonGroupSelectionChanged(app, event)
            app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text;
            app.SelectFieldListBoxValueChanged;            
        end

        % Value changed function: ContrastSwitch
        function ContrastSwitchValueChanged(app, event)
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;
            
            app.contrast = lower(app.ContrastSwitch.Value);
            axes = app.getAxesFromTab();            
            app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotSpectrogram( axes(2), app.which_signal, str2double(app.current_recording), app.current_indx, app.contrast );

            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
        end

        % Value changed function: SelectWearableListBox
        function SelectWearableListBoxValueChanged(app, event)
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;

            axes = app.getAxesFromTab();            
            app.current_wearable = strsplit(app.SelectWearableListBox.Value, ' - ');
            app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.wearables_obj.plotWearable( axes(3), app.current_wearable{1}, app.current_wearable{2}, app.recording_limits );
            
            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualizationWindowUIFigure = uifigure('Visible', 'off');
            app.VisualizationWindowUIFigure.Name = 'Visualization Window';
            app.VisualizationWindowLabel = uilabel(app.VisualizationWindowUIFigure);
            app.VisualizationWindowLabel.Text = 'Visualization Window';
            app.Panel = uipanel(app.VisualizationWindowUIFigure);
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab_1 = uitab(app.TabGroup);
            app.Tab_1.Title = '(1,1,1)';
            app.UIAxes1 = uiaxes(app.Tab_1);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '(2,1,2)';
            app.UIAxes2_top = uiaxes(app.Tab_2);
            title(app.UIAxes2_top, 'Title')
            xlabel(app.UIAxes2_top, 'X')
            ylabel(app.UIAxes2_top, 'Y')
            zlabel(app.UIAxes2_top, 'Z')
            app.UIAxes2_bottom = uiaxes(app.Tab_2);
            title(app.UIAxes2_bottom, 'Title')
            xlabel(app.UIAxes2_bottom, 'X')
            ylabel(app.UIAxes2_bottom, 'Y')
            zlabel(app.UIAxes2_bottom, 'Z')
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '(2,2,3)';
            app.UIAxes3_bottom = uiaxes(app.Tab_3);
            title(app.UIAxes3_bottom, 'Title')
            xlabel(app.UIAxes3_bottom, 'X')
            ylabel(app.UIAxes3_bottom, 'Y')
            zlabel(app.UIAxes3_bottom, 'Z')
            app.UIAxes3_topleft = uiaxes(app.Tab_3);
            title(app.UIAxes3_topleft, 'Title')
            xlabel(app.UIAxes3_topleft, 'X')
            ylabel(app.UIAxes3_topleft, 'Y')
            zlabel(app.UIAxes3_topleft, 'Z')
            app.UIAxes3_topright = uiaxes(app.Tab_3);
            title(app.UIAxes3_topright, 'Title')
            xlabel(app.UIAxes3_topright, 'X')
            ylabel(app.UIAxes3_topright, 'Y')
            zlabel(app.UIAxes3_topright, 'Z')
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '(2,2,4)';
            app.UIAxes4_topleft = uiaxes(app.Tab_4);
            title(app.UIAxes4_topleft, 'Title')
            xlabel(app.UIAxes4_topleft, 'X')
            ylabel(app.UIAxes4_topleft, 'Y')
            zlabel(app.UIAxes4_topleft, 'Z')
            app.UIAxes4_topright = uiaxes(app.Tab_4);
            title(app.UIAxes4_topright, 'Title')
            xlabel(app.UIAxes4_topright, 'X')
            ylabel(app.UIAxes4_topright, 'Y')
            zlabel(app.UIAxes4_topright, 'Z')
            app.UIAxes4_bottomleft = uiaxes(app.Tab_4);
            title(app.UIAxes4_bottomleft, 'Title')
            xlabel(app.UIAxes4_bottomleft, 'X')
            ylabel(app.UIAxes4_bottomleft, 'Y')
            zlabel(app.UIAxes4_bottomleft, 'Z')
            app.UIAxes4_bottomright = uiaxes(app.Tab_4);
            title(app.UIAxes4_bottomright, 'Title')
            xlabel(app.UIAxes4_bottomright, 'X')
            ylabel(app.UIAxes4_bottomright, 'Y')
            zlabel(app.UIAxes4_bottomright, 'Z')
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '(3,1,3)';
            app.UIAxes5_top = uiaxes(app.Tab_5);
            title(app.UIAxes5_top, 'Title')
            xlabel(app.UIAxes5_top, 'X')
            ylabel(app.UIAxes5_top, 'Y')
            zlabel(app.UIAxes5_top, 'Z')
            app.UIAxes5_middle = uiaxes(app.Tab_5);
            title(app.UIAxes5_middle, 'Title')
            xlabel(app.UIAxes5_middle, 'X')
            ylabel(app.UIAxes5_middle, 'Y')
            zlabel(app.UIAxes5_middle, 'Z')
            app.UIAxes5_bottom = uiaxes(app.Tab_5);
            title(app.UIAxes5_bottom, 'Title')
            xlabel(app.UIAxes5_bottom, 'X')
            ylabel(app.UIAxes5_bottom, 'Y')
            zlabel(app.UIAxes5_bottom, 'Z')
            app.eventosTab = uitab(app.TabGroup);
            app.eventosTab.Title = 'eventos';
            app.SelectFieldListBox = uilistbox(app.Panel);
            app.SelectFieldListBox.Multiselect = 'on';
            app.SelectFieldListBox.ValueChangedFcn = createCallbackFcn(app, @SelectFieldListBoxValueChanged, true);
            app.SelectFieldListBox.Value = {'Item 1'};
            app.SelectFieldLabel = uilabel(app.Panel);
            app.SelectFieldLabel.Text = 'Select Field:';
            app.FieldSelectedLabel = uilabel(app.Panel);
            app.FieldSelectedLabel.Text = '  Field Selected';
            app.Button_Previous = uibutton(app.Panel, 'push');
            app.Button_Previous.ButtonPushedFcn = createCallbackFcn(app, @Button_PreviousPushed, true);
            app.Button_Previous.Text = '<<';
            app.Button_Next = uibutton(app.Panel, 'push');
            app.Button_Next.ButtonPushedFcn = createCallbackFcn(app, @Button_NextPushed, true);
            app.Button_Next.Text = '>>';
            app.Warning = uilabel(app.Panel);
            app.Warning.Visible = 'off';
            app.Warning.Text = 'This file does not contain this type of data.';
            app.SelectRecordingListBox = uilistbox(app.Panel);
            app.SelectRecordingListBox.ValueChangedFcn = createCallbackFcn(app, @SelectRecordingListBoxValueChanged, true);
            app.SelectRecordingListBox.Visible = 'off';
            app.SelectRecordingListBoxLabel = uilabel(app.Panel);
            app.SelectRecordingListBoxLabel.Visible = 'off';
            app.SelectRecordingListBoxLabel.Text = 'Select Recording:';
            app.FileDropDown = uidropdown(app.Panel);
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.CompareAllEventsButton = uibutton(app.Panel, 'push');
            app.CompareAllEventsButton.ButtonPushedFcn = createCallbackFcn(app, @CompareAllEventsButtonPushed, true);
            app.CompareAllEventsButton.Visible = 'off';
            app.CompareAllEventsButton.Text = 'Compare all events';
            app.EndingDateDatePickerLabel = uilabel(app.Panel);
            app.EndingDateDatePickerLabel.Visible = 'off';
            app.EndingDateDatePickerLabel.Text = 'Ending Date';
            app.EndingDateDatePicker = uidatepicker(app.Panel);
            app.EndingDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndingDateDatePickerValueChanged, true);
            app.EndingDateDatePicker.Visible = 'off';
            app.StartingDateDatePickerLabel = uilabel(app.Panel);
            app.StartingDateDatePickerLabel.Visible = 'off';
            app.StartingDateDatePickerLabel.Text = 'Starting Date';
            app.StartingDateDatePicker = uidatepicker(app.Panel);
            app.StartingDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartingDateDatePickerValueChanged, true);
            app.StartingDateDatePicker.Visible = 'off';
            app.SelecttypeofEventsListBoxLabel = uilabel(app.Panel);
            app.SelecttypeofEventsListBoxLabel.Visible = 'off';
            app.SelecttypeofEventsListBoxLabel.Text = 'Select type of Events:';
            app.SelecttypeofEventsListBox = uilistbox(app.Panel);
            app.SelecttypeofEventsListBox.ValueChangedFcn = createCallbackFcn(app, @SelecttypeofEventsListBoxValueChanged, true);
            app.SelecttypeofEventsListBox.Visible = 'off';
            app.NumberoftimestampsLabel = uilabel(app.Panel);
            app.NumberoftimestampsLabel.Visible = 'off';
            app.NumberoftimestampsLabel.Text = 'Number of timestamps(±):';
            app.TimestampsSpinner = uispinner(app.Panel);
            app.TimestampsSpinner.Limits = [1 Inf];
            app.TimestampsSpinner.ValueChangedFcn = createCallbackFcn(app, @TimestampsSpinnerValueChanged, true);
            app.TimestampsSpinner.Visible = 'off';
            app.TimestampsSpinner.Value = 5;
            app.TypeSignalButtonGroup = uibuttongroup(app.Panel);
            app.TypeSignalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeSignalButtonGroupSelectionChanged, true);
            app.TypeSignalButtonGroup.Title = 'Type of Signal';
            app.TypeSignalButtonGroup.Visible = 'off';
            app.RawButton = uiradiobutton(app.TypeSignalButtonGroup);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.LatestFilteredButton = uiradiobutton(app.TypeSignalButtonGroup);
            app.LatestFilteredButton.Text = 'Latest Filtered';
            app.ECGCleanedButton = uiradiobutton(app.TypeSignalButtonGroup);
            app.ECGCleanedButton.Text = 'ECG Cleaned';
            app.ContrastSwitch = uiswitch(app.Panel, 'slider');
            app.ContrastSwitch.Items = {'Normal', 'High'};
            app.ContrastSwitch.ValueChangedFcn = createCallbackFcn(app, @ContrastSwitchValueChanged, true);
            app.ContrastSwitch.Visible = 'off';
            app.ContrastSwitch.Value = 'Normal';
            app.SpectrogramContrastLabel = uilabel(app.Panel);
            app.SpectrogramContrastLabel.Visible = 'off';
            app.SpectrogramContrastLabel.Text = 'Spectrogram Contrast:';
            app.SelectWearableListBox = uilistbox(app.Panel);
            app.SelectWearableListBox.ValueChangedFcn = createCallbackFcn(app, @SelectWearableListBoxValueChanged, true);
            app.SelectWearableListBox.Visible = 'off';
            app.SelectWearableListBoxLabel = uilabel(app.Panel);
            app.SelectWearableListBoxLabel.Visible = 'off';
            app.SelectWearableListBoxLabel.Text = 'Select Wearable:';
            app.ReadyLamp = uilamp(app.VisualizationWindowUIFigure);
            app.ReadyLamp_Label = uilabel(app.VisualizationWindowUIFigure);
            app.ReadyLamp_Label.Text = 'Ready';
            app.Label = uilabel(app.VisualizationWindowUIFigure);
            app.Label.Text = 'Neuroengineering and Computational Neuroscience, i3S 2023  |  https://github.com/NCN-Lab/DBScope';
            % Show the figure after all components are created
            app.VisualizationWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizationWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualizationWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualizationWindowUIFigure)
        end
    end
end